classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        A_refLabel                      matlab.ui.control.Label
        A_Ref                           matlab.ui.control.Label
        FlossenanzahlEditField          matlab.ui.control.NumericEditField
        FlossenanzahlEditFieldLabel     matlab.ui.control.Label
        StreckungEditField              matlab.ui.control.NumericEditField
        StreckungEditFieldLabel         matlab.ui.control.Label
        HinterkantenpfeilungEditField   matlab.ui.control.NumericEditField
        HinterkantenpfeilungEditFieldLabel  matlab.ui.control.Label
        VorderkantenpfeilungEditField   matlab.ui.control.NumericEditField
        VorderkantenpfeilungEditFieldLabel  matlab.ui.control.Label
        FlaechenverhaeltnisEditField    matlab.ui.control.NumericEditField
        FlchenverhltnisEditFieldLabel   matlab.ui.control.Label
        RumpfdurchmesserEditField       matlab.ui.control.NumericEditField
        RumpfdurchmesserEditFieldLabel  matlab.ui.control.Label
        NasenformDropDown               matlab.ui.control.DropDown
        NasenformDropDownLabel          matlab.ui.control.Label
        RumpflaengeEditField            matlab.ui.control.NumericEditField
        RumpflngeEditFieldLabel         matlab.ui.control.Label
        NasenlaengeEditField            matlab.ui.control.NumericEditField
        NasenlngeEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        d_flosse = 0.003; % 3 mm
        u_mittel = 30; % 30m/s
        mu0 = 17.17e-6;
        Temp0 = 288.15; % Kelvin
        p0 = 1.225; % kg/m3
        v0 = 14.607e−5; % kinematische Viskositaet auf Seehoehe
    end
    
    methods (Access = private)
        
        function results = gesamtwiderstand(app)
            
        end
        
        function results = gesamtwiderstandsbeiwert(app)
            
        end
        
        function results = c_w_flosse_min(app, re)
            results = 2 * c_f_tu(app, re) * (1+k_flosse(app) * cos(phi50(app))^2);
        end
        
        function results = k_flosse(app)
            delta = app.d_flosse/l_m(app);
            results = 2.7 * delta + 100 * delta^4;
            
        end
        
        function results = l_m(app) % complete, aus folien
            results = A_Flosse(app)/s(app);
        end

        function result = s(app) % complete
            result = sqrt(app.StreckungEditField.Value*A_Flosse(app));
        end
        
        function results = A_Flosse(app) % complete
            results = A_Lat(app)*app.FlaechenverhaeltnisEditField.Value; 
        end

        function results = A_Lat(app) % complete
            results = app.RumpfdurchmesserEditField.Value * app.RumpflaengeEditField.Value + A_Nose(app);
            
        end
        
        function results = A_Nose(app) % complete
            % 0: parabel
            % 1: ellipse
            if app.NasenformDropDown.ValueIndex == 0 
                results = (app.RumpfdurchmesserEditField.Value*app.NasenlaengeEditField.Value)/3; % A(l,r)=(2/3)*l*r
            else
                results = 0.25 * app.RumpfdurchmesserEditField.Value * app.NasenlaengeEditField.Value * pi; % 0.5 * r * l
            end
            
        end
        
        function result = c_f_tu(~,re)
            result = 0.455/(log10(re)^2.58);
            
        end
        
        function results = phi50(app) % TODO
            results = -1;
        end
        
        function result = reynolds(app,laenge)
            result = (app.u_mittel * laenge) / app.v0;
        end
        
        function result = viskositaet(app)
            result = app.mu0 * (Temp/273.15)^0.76;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RumpfdurchmesserEditField
        function RumpfdurchmesserEditFieldValueChanged(app, event)
            % A_ref ausrechnen
            value = app.RumpfdurchmesserEditField.Value;
            app.A_Ref.Text = compose("%9.3f", 0.5 * pi * value^2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.NasenlngeEditFieldLabel = uilabel(app.GridLayout);
            app.NasenlngeEditFieldLabel.Text = 'Nasenlänge';
            app.NasenlaengeEditField = uieditfield(app.GridLayout, 'numeric');
            app.NasenlaengeEditField.Limits = [0 Inf];
            app.RumpflngeEditFieldLabel = uilabel(app.GridLayout);
            app.RumpflngeEditFieldLabel.Text = 'Rumpflänge';
            app.RumpflaengeEditField = uieditfield(app.GridLayout, 'numeric');
            app.RumpflaengeEditField.Limits = [0 Inf];
            app.NasenformDropDownLabel = uilabel(app.GridLayout);
            app.NasenformDropDownLabel.Text = 'Nasenform';
            app.NasenformDropDown = uidropdown(app.GridLayout);
            app.NasenformDropDown.Items = {'Parabel', 'Ellipse'};
            app.NasenformDropDown.Value = 'Parabel';
            app.RumpfdurchmesserEditFieldLabel = uilabel(app.GridLayout);
            app.RumpfdurchmesserEditFieldLabel.Text = 'Rumpfdurchmesser';
            app.RumpfdurchmesserEditField = uieditfield(app.GridLayout, 'numeric');
            app.RumpfdurchmesserEditField.Limits = [35 40];
            app.RumpfdurchmesserEditField.ValueChangedFcn = createCallbackFcn(app, @RumpfdurchmesserEditFieldValueChanged, true);
            app.RumpfdurchmesserEditField.Value = 35;
            app.FlchenverhltnisEditFieldLabel = uilabel(app.GridLayout);
            app.FlchenverhltnisEditFieldLabel.Text = 'Flächenverhältnis';
            app.FlaechenverhaeltnisEditField = uieditfield(app.GridLayout, 'numeric');
            app.VorderkantenpfeilungEditFieldLabel = uilabel(app.GridLayout);
            app.VorderkantenpfeilungEditFieldLabel.Text = 'Vorderkantenpfeilung';
            app.VorderkantenpfeilungEditField = uieditfield(app.GridLayout, 'numeric');
            app.VorderkantenpfeilungEditField.Limits = [0 90];
            app.HinterkantenpfeilungEditFieldLabel = uilabel(app.GridLayout);
            app.HinterkantenpfeilungEditFieldLabel.Text = 'Hinterkantenpfeilung';
            app.HinterkantenpfeilungEditField = uieditfield(app.GridLayout, 'numeric');
            app.HinterkantenpfeilungEditField.Limits = [0 90];
            app.StreckungEditFieldLabel = uilabel(app.GridLayout);
            app.StreckungEditFieldLabel.Text = 'Streckung';
            app.StreckungEditField = uieditfield(app.GridLayout, 'numeric');
            app.FlossenanzahlEditFieldLabel = uilabel(app.GridLayout);
            app.FlossenanzahlEditFieldLabel.Text = 'Flossenanzahl';
            app.FlossenanzahlEditField = uieditfield(app.GridLayout, 'numeric');
            app.FlossenanzahlEditField.Limits = [2 8];
            app.FlossenanzahlEditField.RoundFractionalValues = 'on';
            app.FlossenanzahlEditField.Value = 3;
            app.A_Ref = uilabel(app.GridLayout);
            app.A_refLabel = uilabel(app.GridLayout);
            app.A_refLabel.Interpreter = 'latex';
            app.A_refLabel.Text = '$A_{ref}$';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end